classdef SettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GACoptionsPanel                 matlab.ui.container.Panel
        trmarker                        matlab.ui.control.NumericEditField
        TRMarkerEditFieldLabel          matlab.ui.control.Label
        If0RTEEGwilltrytoautodetectLabel  matlab.ui.control.Label
        trgap                           matlab.ui.control.NumericEditField
        SamplesperTRLabel               matlab.ui.control.Label
        numberofTRstouseforGACLabel     matlab.ui.control.Label
        ntr                             matlab.ui.control.NumericEditField
        NTRLabel                        matlab.ui.control.Label
        LoadSettingsButton              matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        FramesPerSecond                 matlab.ui.control.NumericEditField
        FramesPerSecondEditFieldLabel   matlab.ui.control.Label
        secondsLabel                    matlab.ui.control.Label
        DisplayWidth                    matlab.ui.control.NumericEditField
        DisplaywidthEditFieldLabel      matlab.ui.control.Label
        DeletePost                      matlab.ui.control.Button
        DeletePre                       matlab.ui.control.Button
        AddPost                         matlab.ui.control.Button
        AddPre                          matlab.ui.control.Button
        SelectedPostProcessing          matlab.ui.control.ListBox
        SelectedPostProcessingListBoxLabel  matlab.ui.control.Label
        SelectedPreProcessing           matlab.ui.control.ListBox
        SelectedPreProcessingListBoxLabel  matlab.ui.control.Label
        StoreSettingsButton             matlab.ui.control.Button
        PostProcessingfunctionsListBox  matlab.ui.control.ListBox
        PostProcessingfunctionsListBoxLabel  matlab.ui.control.Label
        uV0forautoscaleLabel            matlab.ui.control.Label
        ScaleSize                       matlab.ui.control.NumericEditField
        ScaleSizeEditFieldLabel         matlab.ui.control.Label
        PreProcessingfunctionsListBox   matlab.ui.control.ListBox
        PreProcessingfunctionsListBoxLabel  matlab.ui.control.Label
        DisplayOptionsPanel             matlab.ui.container.Panel
    end

    
    properties (Access = private)
        CallingApp % The instance of RealTimeEEGUI used to create the settings dialog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGGUIapp)
            app.CallingApp = EEGGUIapp;
            Path = fileparts(mfilename('fullpath'));
            if isunix
                delimiter = '/';
            else
                delimiter = '\';
                
            end
           
            CurrentPath = cd();
            
            % load pre and post processing options
            cd([Path, delimiter, 'PostprocessingFunctions'])
            list = ls();
            if isunix
                list = split(list);
                list(end) = [];
            else
                list(1:2) = [];
                list = string(list);
            end
            i = 1;
            while i <= length(list)
                if ~strcmp(list{i}(end-1:end), '.m')
                    list(i) = [];
                    i = i - 1;
                end
                i = i + 1;
            end
            app.PostProcessingfunctionsListBox.Items = list;
            
            
            cd([Path, delimiter, 'PreprocessingFunctions'])
            list = ls();
            if isunix
                list = split(list);
                list(end) = [];
            else
                list(1:2) = [];
                list = string(list);
            end
            i = 1;
            while i <= length(list)
                if ~strcmp(list{i}(end-1:end), '.m')
                    list(i) = [];
                    i = i - 1;
                end
                i = i + 1;
            end
            app.PreProcessingfunctionsListBox.Items = list;
            cd(CurrentPath);
        end

        % Button pushed function: StoreSettingsButton
        function StoreSettingsButtonPushed(app, event)
            app.CallingApp.ScaleSize = app.ScaleSize.Value;
            app.CallingApp.PreProcessing = app.SelectedPreProcessing.Items;
            app.CallingApp.PostProcessing = app.SelectedPostProcessing.Items;
            app.CallingApp.FrameRate = app.FramesPerSecond.Value;
            app.CallingApp.WindowSize = app.DisplayWidth.Value;
            app.CallingApp.NTR = app.ntr.Value;
            app.CallingApp.trMarker = app.trmarker.Value;
            app.CallingApp.trGap = app.trgap.Value;
            fprintf('\nFrameRate = %.1f Frames Per Second\n', app.FramesPerSecond.Value)
            fprintf('\nDisplay Width = %.1f Seconds\n', app.DisplayWidth.Value)
            fprintf('\nScale Size = %.1f \n', app.ScaleSize.Value)
            fprintf('\nPreprocessing Options: \n')
            for i = 1:length(app.CallingApp.PreProcessing)
                fprintf('\t%s \n', app.CallingApp.PreProcessing{i})
            end
            fprintf('\nPostprocessing Options: \n')
            for i = 1:length(app.CallingApp.PostProcessing)
                fprintf('\t%s \n', app.CallingApp.PostProcessing{i})
            end
            fprintf('\n SETTING STORED! \n')
        end

        % Value changed function: ScaleSize
        function ScaleSizeValueChanged(app, event)
            value = app.ScaleSize.Value;
            
        end

        % Button pushed function: AddPre
        function AddPreButtonPushed(app, event)
            Val = app.PreProcessingfunctionsListBox.Value;
            app.SelectedPreProcessing.Items = [app.SelectedPreProcessing.Items, {Val}];
        end

        % Button pushed function: DeletePre
        function DeletePreButtonPushed(app, event)
            Val = app.SelectedPreProcessing.Value;

            for i = 1:length(app.SelectedPreProcessing.Items)
                if  i <= length(app.SelectedPreProcessing.Items) && ...
                        isequal(app.SelectedPreProcessing.Items{i}, Val)
                    app.SelectedPreProcessing.Items(i) = [];
                end
            end
        end

        % Button pushed function: AddPost
        function AddPostButtonPushed(app, event)
            Val = app.PostProcessingfunctionsListBox.Value;
            app.SelectedPostProcessing.Items = [app.SelectedPostProcessing.Items, {Val}];
        end

        % Button pushed function: DeletePost
        function DeletePostButtonPushed(app, event)
            Val = app.SelectedPostProcessing.Value;

            for i = 1:length(app.SelectedPostProcessing.Items)
                if  i <= length(app.SelectedPostProcessing.Items) && ...
                        isequal(app.SelectedPostProcessing.Items{i}, Val)
                    app.SelectedPostProcessing.Items(i) = [];
                end
            end            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            settings.FR = app.FramesPerSecond.Value;
            settings.DW = app.DisplayWidth.Value;
            settings.SS = app.ScaleSize.Value;
            settings.PreProcessing = app.SelectedPreProcessing.Items;
            settings.PostProcessing = app.SelectedPostProcessing.Items;
            settings.TRG = app.trgap.Value;
            settings.TRM = app.trmarker.Value;
            settings.NTR = app.ntr.Value;
            uisave({'settings'});
            fprintf('Settings Saved successfully \n')
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            uiopen('*.mat');
            if ~exist('settings', 'var')
                warning('Invalid file selected. No settings loaded');
            else
                app.FramesPerSecond.Value = settings.FR;
                app.DisplayWidth.Value = settings.DW;
                app.ScaleSize.Value = settings.SS;
                app.SelectedPreProcessing.Items = settings.PreProcessing;
                app.SelectedPostProcessing.Items = settings.PostProcessing;
                app.trgap.Value = settings.TRG;
                app.trmarker.Value = settings.TRM;
                app.ntr.Value = settings.NTR;
                fprintf('Settings Loaded successfully \n')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.PreProcessingfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.PreProcessingfunctionsListBoxLabel.Text = 'Pre-Processing functions';
            app.PreProcessingfunctionsListBox = uilistbox(app.UIFigure);
            app.ScaleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleSizeEditFieldLabel.Text = 'Scale Size';
            app.ScaleSize = uieditfield(app.UIFigure, 'numeric');
            app.ScaleSize.ValueChangedFcn = createCallbackFcn(app, @ScaleSizeValueChanged, true);
            app.uV0forautoscaleLabel = uilabel(app.UIFigure);
            app.uV0forautoscaleLabel.Text = '(uV - 0 for auto-scale)';
            app.PostProcessingfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.PostProcessingfunctionsListBoxLabel.Text = 'Post-Processing functions';
            app.PostProcessingfunctionsListBox = uilistbox(app.UIFigure);
            app.StoreSettingsButton = uibutton(app.UIFigure, 'push');
            app.StoreSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @StoreSettingsButtonPushed, true);
            app.StoreSettingsButton.Text = 'Store Settings';
            app.SelectedPreProcessingListBoxLabel = uilabel(app.UIFigure);
            app.SelectedPreProcessingListBoxLabel.Text = 'Selected Pre-Processing';
            app.SelectedPreProcessing = uilistbox(app.UIFigure);
            app.SelectedPreProcessing.Items = {};
            app.SelectedPreProcessing.Value = {};
            app.SelectedPostProcessingListBoxLabel = uilabel(app.UIFigure);
            app.SelectedPostProcessingListBoxLabel.Text = 'Selected Post-Processing';
            app.SelectedPostProcessing = uilistbox(app.UIFigure);
            app.SelectedPostProcessing.Items = {};
            app.SelectedPostProcessing.Value = {};
            app.AddPre = uibutton(app.UIFigure, 'push');
            app.AddPre.ButtonPushedFcn = createCallbackFcn(app, @AddPreButtonPushed, true);
            app.AddPre.Text = 'Add';
            app.AddPost = uibutton(app.UIFigure, 'push');
            app.AddPost.ButtonPushedFcn = createCallbackFcn(app, @AddPostButtonPushed, true);
            app.AddPost.Text = 'Add';
            app.DeletePre = uibutton(app.UIFigure, 'push');
            app.DeletePre.ButtonPushedFcn = createCallbackFcn(app, @DeletePreButtonPushed, true);
            app.DeletePre.Text = 'Delete';
            app.DeletePost = uibutton(app.UIFigure, 'push');
            app.DeletePost.ButtonPushedFcn = createCallbackFcn(app, @DeletePostButtonPushed, true);
            app.DeletePost.Text = 'Delete';
            app.DisplaywidthEditFieldLabel = uilabel(app.UIFigure);
            app.DisplaywidthEditFieldLabel.Text = 'Display width';
            app.DisplayWidth = uieditfield(app.UIFigure, 'numeric');
            app.DisplayWidth.Value = 10;
            app.secondsLabel = uilabel(app.UIFigure);
            app.secondsLabel.Text = '(seconds)';
            app.FramesPerSecondEditFieldLabel = uilabel(app.UIFigure);
            app.FramesPerSecondEditFieldLabel.Text = 'Frames Per Second';
            app.FramesPerSecond = uieditfield(app.UIFigure, 'numeric');
            app.FramesPerSecond.Value = 1;
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.GACoptionsPanel = uipanel(app.UIFigure);
            app.GACoptionsPanel.Title = 'GAC options';
            app.NTRLabel = uilabel(app.GACoptionsPanel);
            app.NTRLabel.Text = 'NTR';
            app.ntr = uieditfield(app.GACoptionsPanel, 'numeric');
            app.ntr.Value = 10;
            app.numberofTRstouseforGACLabel = uilabel(app.GACoptionsPanel);
            app.numberofTRstouseforGACLabel.Text = '(number of TRs to use for GAC)';
            app.SamplesperTRLabel = uilabel(app.GACoptionsPanel);
            app.SamplesperTRLabel.Text = 'Samples per TR';
            app.trgap = uieditfield(app.GACoptionsPanel, 'numeric');
            app.If0RTEEGwilltrytoautodetectLabel = uilabel(app.GACoptionsPanel);
            app.If0RTEEGwilltrytoautodetectLabel.Text = '(If 0, RTEEG will try to autodetect)';
            app.TRMarkerEditFieldLabel = uilabel(app.GACoptionsPanel);
            app.TRMarkerEditFieldLabel.Text = 'TR Marker';
            app.trmarker = uieditfield(app.GACoptionsPanel, 'numeric');
            app.trmarker.Value = 255;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end